classdef Proto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        YEditField                matlab.ui.control.NumericEditField
        YEditFieldLabel           matlab.ui.control.Label
        XEditField                matlab.ui.control.NumericEditField
        XEditFieldLabel           matlab.ui.control.Label
        EndEffectorPositionLabel  matlab.ui.control.Label
        RightButton               matlab.ui.control.Button
        LeftButton                matlab.ui.control.Button
        DownButton                matlab.ui.control.Button
        UpButton                  matlab.ui.control.Button
        UpdateDHParametersButton  matlab.ui.control.Button
        CreateBotButton           matlab.ui.control.Button
        alpha3Spinner             matlab.ui.control.Spinner
        alpha3SpinnerLabel        matlab.ui.control.Label
        a3Spinner                 matlab.ui.control.Spinner
        a3SpinnerLabel            matlab.ui.control.Label
        theta3Spinner             matlab.ui.control.Spinner
        theta3SpinnerLabel        matlab.ui.control.Label
        alpha2Spinner             matlab.ui.control.Spinner
        alpha2SpinnerLabel        matlab.ui.control.Label
        a2Spinner                 matlab.ui.control.Spinner
        a2SpinnerLabel            matlab.ui.control.Label
        d2Spinner                 matlab.ui.control.Spinner
        d2SpinnerLabel            matlab.ui.control.Label
        alpha1Spinner             matlab.ui.control.Spinner
        alpha1SpinnerLabel        matlab.ui.control.Label
        a1Spinner                 matlab.ui.control.Spinner
        a1SpinnerLabel            matlab.ui.control.Label
        d1Spinner                 matlab.ui.control.Spinner
        d1SpinnerLabel            matlab.ui.control.Label
        PenSlider                 matlab.ui.control.Slider
        PenSliderLabel            matlab.ui.control.Label
        Arm2Slider                matlab.ui.control.Slider
        Arm2SliderLabel           matlab.ui.control.Label
        Arm1Slider                matlab.ui.control.Slider
        Arm1SliderLabel           matlab.ui.control.Label
        ArduinoStatusLamp         matlab.ui.control.Lamp
        ArduinoStatusLampLabel    matlab.ui.control.Label
        MainPlot                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        jointangles = [-pi/4 -pi/4 5]; % Description
        robot; 
    end
    
    methods (Access = private)
        
        function [err, joint] = inv_kine(app, dir)
            %dir is a boolean array with dir(1) being up, dir(2) being
            %down, dir(3) and dir(4) being left and right
            err = 0;
            dt = 0.1; %Step increment when pressing the buttons

            curr_pos = app.robot.fkine(app.jointangles);
            new_pos = curr_pos;
            
            if dir(1)
                new_pos.t(2) = new_pos.t(2) + dt;
            elseif dir(2)
                new_pos.t(2) = new_pos.t(2) - dt;
            elseif dir(3)
                new_pos.t(1) = new_pos.t(1) - dt;
            else
                new_pos.t(1) = new_pos.t(1) + dt;
            end
            
            joint = app.robot.ikine(new_pos, app.jointangles, 'mask', [1 1 1 0 0 0]);

            if isempty(joint)
                err = 1;
                msgbox('cannot move in that direction', 'Error', 'error');
            end
        end
        
        function [] = update_ee_pos(app)
            ee_pos = app.robot.fkine(app.jointangles);
            app.XEditField.Value = ee_pos.t(1);
            app.YEditField.Value = ee_pos.t(2);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.XEditField.Editable = 'Off';
            app.YEditField.Editable = 'Off';
        end

        % Value changing function: Arm1Slider
        function Arm1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointangles(1) = changingValue;
            app.update_ee_pos();
            app.robot.plot(app.jointangles);

        end

        % Value changing function: Arm2Slider
        function Arm2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointangles(2) = changingValue;
            app.update_ee_pos();
            app.robot.plot(app.jointangles);
            
        end

        % Value changing function: PenSlider
        function PenSliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointangles(3) = changingValue;
            app.robot.plot(app.jointangles);
        end

        % Button pushed function: CreateBotButton
        function CreateBotButtonPushed(app, event)
%             L(1) = Link(['revolute', 'alpha', 0, 'a', 2, 'theta', 0, 'd', 0.1]);     % Main Arm
%             L(2) = Link(['revolute', 'alpha', 0, 'a', 1.7, 'theta', 0, 'd', 0.3]);     % Second Arm
%             L(3) = Link(['prismatic', 'alpha', 0, 'a', 1.5, 'theta', 0, 'd', 0.3]);   % Prismatic joint (pen)
%             L(1) = Link([0 2.0 3 0 0]);  % Main Arm
%             L(2) = Link([0 0.7 3 pi 0]); % Second Arm
%             L(3) = Link([0 1 1 0 1]);     % Pen arm

            L(1) = Link([0 7.5 8.6 0 0]);  % Main Arm
            L(1).offset = -0.349;
            L(1).qlim = [-2.007 1.13446];
            L(2) = Link([0 2.5 7.5 pi 0]); % Second Arm
            L(2).offset = -0.17;
            L(2).qlim = [-2.007 0];
            L(3) = Link('prismatic', 'theta', 0, 'a', 0, 'alpha', 0);
            % Link ([ theta, d, a, alpha, type]);

            % Estimated DH (in meters)
            % L1: Var       0.068       0.1         0        
            % L2: Var       0.025       0.085       4
            % L3: x         Var         0.095       0
            L(3).qlim = [0 8];
            app.robot = SerialLink(L, "name", "robot");

            %Base offset
            t = eul2tr([pi/2, 0, 0]);
            t(1:3, 4) = [7.9, 0, 0]';
            app.robot.base = t;
            app.update_ee_pos();
            app.robot.plot(app.jointangles);
        end

        % Callback function
        function UpdateDHParametersButtonPushed(app, event)
            % Grab each value from sliders
            % Create new links with these params, and 0 for vars as
            % placeholder
            % Generate new robot and plot with jointangles
            d1 = app.d1Spinner.Value;
            a1 = app.a1Spinner.Value;
            alpha1 = app.alpha1Spinner.Value;

            d2 = app.d2Spinner.Value;
            a2 = app.a2Spinner.Value;
            alpha2 = app.alpha2Spinner.Value;
            
            theta3 = app.theta3Spinner.Value;
            a3 = app.a3Spinner.Value;
            alpha3 = app.alpha3Spinner.Value;
            
            %           L(n) = Link([alpha a theta d1 type]);

            L(1) = Link([0 d1 a1 alpha1 0]);
            L(1).offset = -0.349;
            L(2) = Link([0 d2 a2 alpha2 0]);
            L(2).offset = -0.17;
            L(2).qlim = [-2.007 0];
            L(3) = Link([theta3 1 a3 alpha3 1]);
            L(3).qlim = [0 8];
            app.robot = SerialLink(L, "name", "robot");
            
            %Base offset
            t = eul2tr([pi/2, 0, 0]);
            t(1:3, 4) = [7.9, 0, 0]';
            app.robot.base = t;
            app.update_ee_pos();
            app.robot.plot(app.jointangles);
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)

            [err, joints] = app.inv_kine([1 0 0 0]);
            if err
                return
            end
            app.jointangles = joints;
            app.update_ee_pos();
            app.robot.plot(app.jointangles);

        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            [err, joints] = app.inv_kine([0 0 1 0]);
            if err
                return
            end
            app.jointangles = joints;
            app.update_ee_pos();
            app.robot.plot(app.jointangles);
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            [err, joints] = app.inv_kine([0 1 0 0]);
            if err
                return
            end
            app.jointangles = joints;
            app.update_ee_pos();
            app.robot.plot(app.jointangles);
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            [err, joints] = app.inv_kine([0 0 0 1]);
            if err
                return
            end
            app.jointangles = joints;
            app.update_ee_pos();
            app.robot.plot(app.jointangles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainPlot = uiaxes(app.UIFigure);
            title(app.MainPlot, 'Title')
            xlabel(app.MainPlot, 'X')
            ylabel(app.MainPlot, 'Y')
            zlabel(app.MainPlot, 'Z')
            app.ArduinoStatusLampLabel = uilabel(app.UIFigure);
            app.ArduinoStatusLampLabel.Text = 'Arduino Status';
            app.ArduinoStatusLamp = uilamp(app.UIFigure);
            app.Arm1SliderLabel = uilabel(app.UIFigure);
            app.Arm1SliderLabel.Text = 'Arm 1';
            app.Arm1Slider = uislider(app.UIFigure);
            app.Arm1Slider.Limits = [0 6.28318530717959];
            app.Arm1Slider.ValueChangingFcn = createCallbackFcn(app, @Arm1SliderValueChanging, true);
            app.Arm2SliderLabel = uilabel(app.UIFigure);
            app.Arm2SliderLabel.Text = 'Arm 2';
            app.Arm2Slider = uislider(app.UIFigure);
            app.Arm2Slider.Limits = [0 6.28318530717959];
            app.Arm2Slider.ValueChangingFcn = createCallbackFcn(app, @Arm2SliderValueChanging, true);
            app.PenSliderLabel = uilabel(app.UIFigure);
            app.PenSliderLabel.Text = 'Pen';
            app.PenSlider = uislider(app.UIFigure);
            app.PenSlider.Limits = [0 5];
            app.PenSlider.ValueChangingFcn = createCallbackFcn(app, @PenSliderValueChanging, true);
            app.PenSlider.Value = 5;
            app.d1SpinnerLabel = uilabel(app.UIFigure);
            app.d1SpinnerLabel.Text = 'd1';
            app.d1Spinner = uispinner(app.UIFigure);
            app.d1Spinner.Step = 0.1;
            app.d1Spinner.Value = 0.075;
            app.a1SpinnerLabel = uilabel(app.UIFigure);
            app.a1SpinnerLabel.Text = 'a1';
            app.a1Spinner = uispinner(app.UIFigure);
            app.a1Spinner.Step = 0.1;
            app.a1Spinner.Value = 0.079;
            app.alpha1SpinnerLabel = uilabel(app.UIFigure);
            app.alpha1SpinnerLabel.Text = 'alpha1';
            app.alpha1Spinner = uispinner(app.UIFigure);
            app.alpha1Spinner.Step = 0.1;
            app.alpha1Spinner.Limits = [-3.14159265358979 3.14159265358979];
            app.d2SpinnerLabel = uilabel(app.UIFigure);
            app.d2SpinnerLabel.Text = 'd2';
            app.d2Spinner = uispinner(app.UIFigure);
            app.d2Spinner.Step = 0.1;
            app.d2Spinner.Value = 0.025;
            app.a2SpinnerLabel = uilabel(app.UIFigure);
            app.a2SpinnerLabel.Text = 'a2';
            app.a2Spinner = uispinner(app.UIFigure);
            app.a2Spinner.Step = 0.1;
            app.a2Spinner.Value = 0.086;
            app.alpha2SpinnerLabel = uilabel(app.UIFigure);
            app.alpha2SpinnerLabel.Text = 'alpha2';
            app.alpha2Spinner = uispinner(app.UIFigure);
            app.alpha2Spinner.Step = 0.1;
            app.alpha2Spinner.Limits = [-3.14159265358979 3.14159265358979];
            app.alpha2Spinner.Value = 3.14159265358979;
            app.theta3SpinnerLabel = uilabel(app.UIFigure);
            app.theta3SpinnerLabel.Text = 'theta3';
            app.theta3Spinner = uispinner(app.UIFigure);
            app.theta3Spinner.Step = 0.1;
            app.theta3Spinner.Limits = [-3.14159265358979 3.14159265358979];
            app.a3SpinnerLabel = uilabel(app.UIFigure);
            app.a3SpinnerLabel.Text = 'a3';
            app.a3Spinner = uispinner(app.UIFigure);
            app.a3Spinner.Step = 0.1;
            app.a3Spinner.Value = 0.075;
            app.alpha3SpinnerLabel = uilabel(app.UIFigure);
            app.alpha3SpinnerLabel.Text = 'alpha3';
            app.alpha3Spinner = uispinner(app.UIFigure);
            app.alpha3Spinner.Step = 0.1;
            app.alpha3Spinner.Limits = [-3.14159265358979 3.14159265358979];
            app.CreateBotButton = uibutton(app.UIFigure, 'push');
            app.CreateBotButton.ButtonPushedFcn = createCallbackFcn(app, @CreateBotButtonPushed, true);
            app.CreateBotButton.Text = 'CreateBot';
            app.UpdateDHParametersButton = uibutton(app.UIFigure, 'push');
            app.UpdateDHParametersButton.Text = 'Update DH Parameters';
            app.UpButton = uibutton(app.UIFigure, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.DownButton = uibutton(app.UIFigure, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.LeftButton = uibutton(app.UIFigure, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.UIFigure, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.EndEffectorPositionLabel = uilabel(app.UIFigure);
            app.EndEffectorPositionLabel.Text = '        End Effector Position';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Proto

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end