classdef PathPlanning < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1            matlab.ui.Figure
        TimeanalysisLabel  matlab.ui.control.Label
        UITable            matlab.ui.control.Table
        PRMCheckBox        matlab.ui.control.CheckBox
        DStarCheckBox      matlab.ui.control.CheckBox
        NavigateButton     matlab.ui.control.Button
        PlanpathButton     matlab.ui.control.Button
        DrawBoardButton    matlab.ui.control.Button
        PenUpDownButton    matlab.ui.control.Button
        slider3            matlab.ui.control.Spinner
        ArmLabel           matlab.ui.control.Label
        slider2            matlab.ui.control.Slider
        Joint2Label        matlab.ui.control.Label
        slider1            matlab.ui.control.Slider
        Joint1SliderLabel  matlab.ui.control.Label
        DXformCheckBox     matlab.ui.control.CheckBox
        DebugLog           matlab.ui.control.TextArea
        DebugLogLabel      matlab.ui.control.Label
        ZLabel             matlab.ui.control.Label
        YLabel             matlab.ui.control.Label
        XLabel             matlab.ui.control.Label
        text1              matlab.ui.control.Label
        Create             matlab.ui.control.Button
        axes1              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        robot = [];
        pen_up = true;
        jointangle =[0 -0.44 0.1];
        data_available = false;
        base_joint = 0;
        map = zeros(100);
        path = [];
        pen_color = '#FF55AA';
        planner = [];
    end
    
    methods (Access = private)
        
        function enable_buttons(app)
            set(app.slider1, 'Enable', 'on');
            set(app.slider2, 'Enable', 'on');
            set(app.slider3, 'Enable', 'on');
            set(app.DrawBoardButton, 'Enable', 'on');
            set(app.PenUpDownButton, 'Enable', 'on');
            set(app.PlanpathButton, 'Enable', 'on');

        end

        function disable_buttons(app)
            set(app.Board1, 'Enable', 'off');
            set(app.Board2, 'Enable', 'off');
            set(app.Board3, 'Enable', 'off');
            set(app.Board4, 'Enable', 'off');
            set(app.Board5, 'Enable', 'off');
            set(app.Board6, 'Enable', 'off');
            set(app.Board7, 'Enable', 'off');
            set(app.Board8, 'Enable', 'off');
            set(app.Board9, 'Enable', 'off');

        end


        function update_fkine(app)
            % The core function that plots the current jointangles
            % and draws a sphere on the plot if the end effector is 
            % below the contacting threshold. It also calls
            % the update_motors function if enabled
            app.robot.plot(app.jointangle);
            T = app.robot.fkine(app.jointangle);
            if (T.t(3) <= 0.2)
                plot_sphere(T.t, 0.2, app.pen_color);
            end
            app.XLabel.Text = string(T.t(1));
            app.YLabel.Text = string(T.t(2));
            app.ZLabel.Text = string(T.t(3));

        end
        
        function moveToPos(app, x, y, steps)
            % Moves EE to a given x, y in certain amount of steps
            try
                % Get current T matrix
                T = app.robot.fkine(app.jointangle);
                currX = T.t(1);
                currY = T.t(2);
                points = zeros(steps + 1, 2);
                
                % Generate a list of intermediate points along the way
                deltaX = x - currX;
                deltaY = y - currY;
                stepX = deltaX / steps;
                stepY = deltaY / steps;
                points(:, 1) = currX: stepX: x;
                points(:, 2) = currY: stepY: y;

                % Move along these list of points
                app.xyDeltaMove(points);
            catch
                app.DebugLog.Value = "Unreachable location";
            end
        end

        function xyDeltaMove(app, points)
            % Moves robot across a series of points
            pointArraySize = size(points);
            steps = pointArraySize(1);
            for i = 1:steps
                try
                    % Get current transformation but set x/y values
                    % to target x and y
                    T = app.robot.fkine(app.jointangle);
                    T.t(1)= points(i, 1);
                    T.t(2) = points(i, 2);
                    % Use ikine to generate new joint angles
                    joints = app.robot.ikine(T, app.jointangle, 'mask', [1 1 1 0 0 0]);
                    if ~isempty(joints)
                        app.jointangle = joints;
                    end
                    
                    % Update slider values
                    app.slider1.Value = app.jointangle(1);
                    app.slider2.Value = app.jointangle(2);
                    app.slider3.Value = app.jointangle(3);
                    
                    % Move the robot
                    app.update_fkine();
                    app.DebugLog.Value = "Ikine Success";
                catch
                    app.DebugLog.Value = "Ikine Failure";
                end
                app.DebugLog.Value = "Clear";
            end
        end
        
        function update_board(app)
            % Update board strings
            app.Board1.Text = string(app.board(1,1));
            app.Board2.Text = string(app.board(1,2));
            app.Board3.Text = string(app.board(1,3));
            app.Board4.Text = string(app.board(2,1));
            app.Board5.Text = string(app.board(2,2));
            app.Board6.Text = string(app.board(2,3));
            app.Board7.Text = string(app.board(3,1));
            app.Board8.Text = string(app.board(3,2));
            app.Board9.Text = string(app.board(3,3));
        end

        function PenUp(app)
            % Function to move pen up so it does not plot on the graph
            T = app.robot.fkine(app.jointangle);
            z = T.t(3);
            % Just lift the pen
            while z < 0.5
                app.jointangle(3) = app.jointangle(3) - 0.1;
                T = app.robot.fkine(app.jointangle);
                z = T.t(3);
            end
            app.update_fkine();
            app.pen_up = true;
        end

        function PenDown(app)
            % Function to move pen down to plot
            T = app.robot.fkine(app.jointangle);
            T.t(3) = 0;
            app.jointangle = app.robot.ikine(T, app.jointangle, 'mask', [1 1 1 0 0 0]);
            app.update_fkine();
            app.pen_up = false;
        end
        
        function [] = planPath(app)
            % Function to chart a path from start to goal using the
            % selected algorithm
            tic %Timing the algorithm
            start = [50, 69]; % Start at top middle box
            goals = [70, 69; 70, 49; 70, 30; 50, 30; 30, 30; 30, 49; 30, 69; 50, 69]; %Goal cycles thorugh all boxes clockwise
            % x_vals and y_vals are used to map cost map coordinates to
            % robot coordinates (linear mapping)
            x_vals = [0.1017, 8.9661];
            y_vals = [0.1017, 5.4661];

            for i = 1:length(goals) %Cycle through goals and append path points
                
                if app.PRMCheckBox.Value %PRM requires start and goal to plan the path
                    app.planner.plan();
                    map_path = app.planner.query(start, goals(i, :));
                else
                    app.planner.plan(goals(i, :));
                    map_path = app.planner.query(start);
                end
                % Linear map from cost map points to robot points
                map_path(:,1) = map_path(:,1) .* x_vals(1) + x_vals(2);
                map_path(:,2) = map_path(:,2) .* y_vals(1) + y_vals(2);
                app.path = [app.path; map_path]; % Append the planned points
                start = goals(i, :);               
            end
            t = toc;
            app.DebugLog.Value = ["time taken to plan: ", string(t)];
            
            %Update table
            table_val = app.UITable.Data;

            if app.DXformCheckBox.Value
                table_val{1} = t;
                app.UITable.Data = table_val;
            elseif app.DStarCheckBox.Value
                table_val{2} = t;
                app.UITable.Data = table_val;
            else
                table_val{3} = t;
                app.UITable.Data = table_val;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Create
        function Create_Callback(app, event)
            % Define DH parameters and offsets and qlims
            L(1) = Link([0 0 6.8773 0.0005 0]);
            L(1).offset = -0.2174; % -0.2176
            L(1).qlim = [-pi 0];
            L(2) = Link([0 2.7814 9.5840 3.0 0]);
            L(2).offset = -0.1581; % -0.1580
            L(2).qlim = [-pi 0];
            L(3) =  Link('prismatic', 'theta', -0.0008, 'a', -0.0009, 'alpha', -0.0007);
            L(3).qlim = [0 12];

            % Create robot
            app.robot = SerialLink(L, 'name', 'robot');
            
            % Adjust base
            t = eul2tr([pi/2, 0, 0]);
            t(1:3, 4) = [7.9 0 7.6];
            app.robot.base = t;
            
            % Plot robot
            app.update_fkine();

            app.enable_buttons();

            app.UITable.Data = {0 0 0};
            
        end

        % Callback function
        function Board1ButtonPushed(app, event)
            if app.game.user == app.game.X
                app.game.board(1,1) = app.game.X;
                app.Board1.Text = 'X';
                app.DrawX(1);
            else
                app.game.board(1,1) = app.game.O;
                app.Board1.Text = 'O';
                app.DrawO(1);
            end
            uiresume(app.figure1);
%             app.DrawO(1);
        end

        % Value changed function: slider1
        function slider1ValueChanged(app, event)
            value = app.slider1.Value;
            app.jointangle(1) = value;
            update_fkine(app);
        end

        % Value changed function: slider2
        function slider2ValueChanged(app, event)
            value = app.slider2.Value;
            app.jointangle(2) = value;
            update_fkine(app);
        end

        % Value changed function: slider3
        function slider3ValueChanged(app, event)
            value = app.slider3.Value;
            app.jointangle(3) = value;
            update_fkine(app);
        end

        % Button pushed function: PenUpDownButton
        function PenUpDownButtonPushed(app, event)
            % Toggle pen button
            if app.pen_up == true
                app.PenDown();
                app.PenUpDownButton.Text = "Pen Up";
            else
                app.PenUp();
                app.PenUpDownButton.Text = "Pen Down";
            end
        end

        % Button pushed function: DrawBoardButton
        function DrawBoardButtonPushed(app, event)
%             x = app.boardX.Value;
%             y = app.boardY.Value;
%             s = app.boardS.Value;
            x = 11;
            y = 13.5;
            s = 6;
            stepCt = 30;
            % Left vert
            app.PenUp();
            app.moveToPos(x + s/3, y, stepCt);
            app.PenDown();
            app.moveToPos(x + s/3, y-s, stepCt);

            % Right vert
            app.PenUp();
            app.moveToPos(x + 2*s/3, y-s, stepCt);
            app.PenDown();
            app.moveToPos(x + 2*s/3, y, stepCt);
            
            % Top horiz
            app.PenUp();
            app.moveToPos(x+s, y - s/3, stepCt);
            app.PenDown();
            app.moveToPos(x, y - s/3, stepCt);

            % Bottom horiz
            app.PenUp();
            app.moveToPos(x, y - 2*s/3, stepCt);
            app.PenDown();
            app.moveToPos(x+s, y - 2*s/3, stepCt);
            

            % Make cost map
            app.map(20:79, 40) = ones(60, 1);
            app.map(20:79, 59) = ones(60, 1);
            app.map(40, 20:79) = ones(1, 60);
            app.map(59, 20:79) = ones(1, 60);

        end

        % Callback function
        function Board2ButtonPushed(app, event)
            if app.game.user == app.game.X
                app.game.board(1,2) = app.game.X;
                app.Board2.Text = 'X';
                app.DrawX(2);
            else
                app.game.board(1,2) = app.game.O;
                app.Board2.Text = 'O';
                app.DrawO(2);
            end
            uiresume(app.figure1);
% %             app.DrawO(2);
        end

        % Callback function
        function Board3ButtonPushed(app, event)
            if app.game.user == app.game.X
                app.game.board(1,3) = app.game.X;
                app.Board3.Text = 'X';
                app.DrawX(3);
            else
                app.game.board(1,3) = app.game.O;
                app.Board3.Text = 'O';
                app.DrawO(3);
            end
            uiresume(app.figure1);
% %             app.DrawO(3);
        end

        % Callback function
        function Board4ButtonPushed(app, event)
            if app.game.user == app.game.X
                app.game.board(2,1) = app.game.X;
                app.Board4.Text = 'X';
                app.DrawX(4);
            else
                app.game.board(2,1) = app.game.O;
                app.Board4.Text = 'O';
                app.DrawO(4);
            end
            uiresume(app.figure1);
%             app.DrawO(4);
        end

        % Callback function
        function Board5ButtonPushed(app, event)
            if app.game.user == app.game.X
                app.game.board(2,2) = app.game.X;
                app.Board5.Text = 'X';
                app.DrawX(5);
            else
                app.game.board(2,2) = app.game.O;
                app.Board5.Text = 'O';
                app.DrawO(5);
            end
            uiresume(app.figure1);
%             app.DrawO(5);
        end

        % Callback function
        function Board6ButtonPushed(app, event)
            if app.game.user == app.game.X
                app.game.board(2,3) = app.game.X;
                app.Board6.Text = 'X';
                app.DrawX(6);
            else
                app.game.board(2,3) = app.game.O;
                app.Board6.Text = 'O';
                app.DrawO(6);
            end
            uiresume(app.figure1);
%             app.DrawO(6);
        end

        % Callback function
        function Board7ButtonPushed(app, event)
            if app.game.user == app.game.X
                app.game.board(3,1) = app.game.X;
                app.Board7.Text = 'X';
                app.DrawX(7);
            else
                app.game.board(3,1) = app.game.O;
                app.Board7.Text = 'O';
                app.DrawO(7);
            end
            uiresume(app.figure1);
%             app.DrawO(7);
        end

        % Callback function
        function Board8ButtonPushed(app, event)
            if app.game.user == app.game.X
                app.game.board(3,2) = app.game.X;
                app.Board8.Text = 'X';
                app.DrawX(8);
            else
                app.game.board(3,2) = app.game.O;
                app.Board8.Text = 'O';
                app.DrawO(8);
            end
            uiresume(app.figure1);
%             app.DrawO(8);
        end

        % Callback function
        function Board9ButtonPushed(app, event)
            if app.game.user == app.game.X
                app.game.board(3,3) = app.game.X;
                app.Board9.Text = 'X';
                app.DrawX(9);
            else
                app.game.board(3,3) = app.game.O;
                app.Board9.Text = 'O';
                app.DrawO(9);
            end
            uiresume(app.figure1);
%             app.DrawO(9);
        end

        % Button pushed function: PlanpathButton
        function PlanpathButtonPushed(app, event)

            
            if (app.DStarCheckBox.Value)
                app.planner = Dstar(app.map);
            elseif (app.DXformCheckBox.Value)
                app.planner = DXform(app.map);
            else %Default to PRM since it is the fastest
                app.planner = PRM(app.map);
            end
            app.path = []; % Clear old path if any
            app.planPath(); 
            
            set(app.NavigateButton, 'Enable', 'on'); %Enable navigation after path planning
            
            
        end

        % Button pushed function: NavigateButton
        function NavigateButtonPushed(app, event)

                app.pen_color = '#6af3cf'; % Use a different color to show navigation
                if app.PRMCheckBox.Value
                    % For loop used for PRM since the points are not fine
                    % enough and should be moved in steps (5) to visualize
                    % output
                    for i=1:length(app.path)
                        app.moveToPos(app.path(i,1), app.path(i,2), 5)
                    end
                else
                    app.xyDeltaMove(app.path);
                end
                app.pen_color = '#FF55AA'; %Change back to original color

        end

        % Value changed function: DStarCheckBox
        function DStarCheckBoxValueChanged(app, event)
            % Disable other planners when checked so user cannot select two
            % at the same time
            value = app.DStarCheckBox.Value;
            if value
                set(app.DXformCheckBox, 'Enable', 'off');
                set(app.PRMCheckBox, 'Enable', 'off');
            else
                set(app.DXformCheckBox, 'Enable', 'on');
                set(app.PRMCheckBox, 'Enable', 'on');
            end
        end

        % Value changed function: DXformCheckBox
        function DXformCheckBoxValueChanged(app, event)
            % Disable other planners when checked so user cannot select two
            % at the same time
            value = app.DXformCheckBox.Value;
            if value
                set(app.DStarCheckBox, 'Enable', 'off');
                set(app.PRMCheckBox, 'Enable', 'off');
            else
                set(app.DStarCheckBox, 'Enable', 'on');
                set(app.PRMCheckBox, 'Enable', 'on');
            end
        end

        % Value changed function: PRMCheckBox
        function PRMCheckBoxValueChanged(app, event)
            % Disable other planners when checked so user cannot select two
            % at the same time
            value = app.PRMCheckBox.Value;
            if value
                set(app.DStarCheckBox, 'Enable', 'off');
                set(app.DXformCheckBox, 'Enable', 'off');
            else
                set(app.DStarCheckBox, 'Enable', 'on');
                set(app.DXformCheckBox, 'Enable', 'on');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'twolink';
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.Create = uibutton(app.figure1, 'push');
            app.Create.ButtonPushedFcn = createCallbackFcn(app, @Create_Callback, true);
            app.Create.Tag = 'Create';
            app.Create.Text = 'Create Robot';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Path Planning';
            app.XLabel = uilabel(app.figure1);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.figure1);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.figure1);
            app.ZLabel.Text = 'Z';
            app.DebugLogLabel = uilabel(app.figure1);
            app.DebugLogLabel.Text = 'Debug Log';
            app.DebugLog = uitextarea(app.figure1);
            app.DXformCheckBox = uicheckbox(app.figure1);
            app.DXformCheckBox.ValueChangedFcn = createCallbackFcn(app, @DXformCheckBoxValueChanged, true);
            app.DXformCheckBox.Text = 'DXform';
            app.Joint1SliderLabel = uilabel(app.figure1);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.slider1 = uislider(app.figure1);
            app.slider1.Limits = [-1.2 1.14];
            app.slider1.ValueChangedFcn = createCallbackFcn(app, @slider1ValueChanged, true);
            app.slider1.Enable = 'off';
            app.Joint2Label = uilabel(app.figure1);
            app.Joint2Label.Text = 'Joint 2';
            app.slider2 = uislider(app.figure1);
            app.slider2.Limits = [-2.18 -0.43];
            app.slider2.MajorTicks = [-2.18 -1.6 -1 -0.43];
            app.slider2.ValueChangedFcn = createCallbackFcn(app, @slider2ValueChanged, true);
            app.slider2.Enable = 'off';
            app.slider2.Value = -0.43;
            app.ArmLabel = uilabel(app.figure1);
            app.ArmLabel.Text = 'Pen Joint';
            app.slider3 = uispinner(app.figure1);
            app.slider3.Limits = [-1 15];
            app.slider3.ValueChangedFcn = createCallbackFcn(app, @slider3ValueChanged, true);
            app.slider3.Enable = 'off';
            app.slider3.Value = 1;
            app.PenUpDownButton = uibutton(app.figure1, 'push');
            app.PenUpDownButton.ButtonPushedFcn = createCallbackFcn(app, @PenUpDownButtonPushed, true);
            app.PenUpDownButton.Enable = 'off';
            app.PenUpDownButton.Text = 'Pen Down';
            app.DrawBoardButton = uibutton(app.figure1, 'push');
            app.DrawBoardButton.ButtonPushedFcn = createCallbackFcn(app, @DrawBoardButtonPushed, true);
            app.DrawBoardButton.Enable = 'off';
            app.DrawBoardButton.Text = 'Draw Board';
            app.PlanpathButton = uibutton(app.figure1, 'push');
            app.PlanpathButton.ButtonPushedFcn = createCallbackFcn(app, @PlanpathButtonPushed, true);
            app.PlanpathButton.Enable = 'off';
            app.PlanpathButton.Text = 'Plan path';
            app.NavigateButton = uibutton(app.figure1, 'push');
            app.NavigateButton.ButtonPushedFcn = createCallbackFcn(app, @NavigateButtonPushed, true);
            app.NavigateButton.Enable = 'off';
            app.NavigateButton.Text = 'Navigate';
            app.DStarCheckBox = uicheckbox(app.figure1);
            app.DStarCheckBox.ValueChangedFcn = createCallbackFcn(app, @DStarCheckBoxValueChanged, true);
            app.DStarCheckBox.Text = 'D Star';
            app.PRMCheckBox = uicheckbox(app.figure1);
            app.PRMCheckBox.ValueChangedFcn = createCallbackFcn(app, @PRMCheckBoxValueChanged, true);
            app.PRMCheckBox.Text = 'PRM';
            app.UITable = uitable(app.figure1);
            app.UITable.ColumnName = {'DXForm'; 'DStar'; 'PRM'};
            app.UITable.RowName = {'Time'};
            app.TimeanalysisLabel = uilabel(app.figure1);
            app.TimeanalysisLabel.Text = 'Time analysis';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PathPlanning

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end