classdef ThreeLinkServoRRP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1               matlab.ui.Figure
        Arduino               matlab.ui.container.Menu
        Connect               matlab.ui.container.Menu
        Disconnect            matlab.ui.container.Menu
        ConnectMotorMenu      matlab.ui.container.Menu
        UserDropDown          matlab.ui.control.DropDown
        UserDropDownLabel     matlab.ui.control.Label
        StartGameButton       matlab.ui.control.Button
        DrawBoardButton       matlab.ui.control.Button
        boardS                matlab.ui.control.NumericEditField
        boardY                matlab.ui.control.NumericEditField
        boardX                matlab.ui.control.NumericEditField
        PenUpDownButton       matlab.ui.control.Button
        slider3               matlab.ui.control.Spinner
        ArmLabel              matlab.ui.control.Label
        slider2               matlab.ui.control.Slider
        Joint2Label           matlab.ui.control.Label
        slider1               matlab.ui.control.Slider
        Joint1SliderLabel     matlab.ui.control.Label
        AffectServosCheckBox  matlab.ui.control.CheckBox
        MoveToButton          matlab.ui.control.Button
        StepsSpinner          matlab.ui.control.Spinner
        StepsSpinnerLabel     matlab.ui.control.Label
        YSpinner              matlab.ui.control.Spinner
        YSpinnerLabel         matlab.ui.control.Label
        XSpinner              matlab.ui.control.Spinner
        XSpinnerLabel         matlab.ui.control.Label
        Board9                matlab.ui.control.Button
        Board8                matlab.ui.control.Button
        Board7                matlab.ui.control.Button
        Board6                matlab.ui.control.Button
        Board5                matlab.ui.control.Button
        Board4                matlab.ui.control.Button
        Board3                matlab.ui.control.Button
        Board2                matlab.ui.control.Button
        Board1                matlab.ui.control.Button
        DebugLog              matlab.ui.control.TextArea
        DebugLogLabel         matlab.ui.control.Label
        ZLabel                matlab.ui.control.Label
        YLabel                matlab.ui.control.Label
        XLabel                matlab.ui.control.Label
        text1                 matlab.ui.control.Label
        Create                matlab.ui.control.Button
        axes1                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        device =[]; % Arduino connection
        robot =[]; % Connection to the robot via Serial Link
        % Servo Motors
        servo_motor1 = [];
        servo_motor2 = [];
        servo_motor3 = [];
        % Motor coefficients after using polyfit to map voltage to angles
        motor1_coeff = [-0.052643748592713 -0.026509530715195 -0.324540945800541  0.557795634418533];
        motor2_coeff = [-0.111066845646552 -0.492130186495053 -1.038303336251936 -0.296208719626575];
        servos_on = false;
        pen_up = true;
        jointangle =[0 -0.44 0.1];
        data_available = false;
        base_joint = 0;
        game = [];
    end
    
    methods (Access = private)
        
        function enable_buttons(app)
            set(app.MoveToButton, 'Enable', 'on');
            set(app.slider1, 'Enable', 'on');
            set(app.slider2, 'Enable', 'on');
            set(app.slider3, 'Enable', 'on');
            set(app.DrawBoardButton, 'Enable', 'on');
            set(app.PenUpDownButton, 'Enable', 'on');

        end

        function disable_buttons(app)
            set(app.MoveToButton, 'Enable', 'off');
            set(app.Board1, 'Enable', 'off');
            set(app.Board2, 'Enable', 'off');
            set(app.Board3, 'Enable', 'off');
            set(app.Board4, 'Enable', 'off');
            set(app.Board5, 'Enable', 'off');
            set(app.Board6, 'Enable', 'off');
            set(app.Board7, 'Enable', 'off');
            set(app.Board8, 'Enable', 'off');
            set(app.Board9, 'Enable', 'off');

        end

        function update_motors(app)
            % This function is what actually moves the servo motors

%             voltage1 = -0.427350427350427 * app.jointangle(1) + 0.487179487179487;
%             voltage2 = -0.457142857142857 * app.jointangle(2) - 0.196571428571429;
            voltage1 = polyval(app.motor1_coeff, app.jointangle(1));
            voltage2 = polyval(app.motor2_coeff, app.jointangle(2));
            if (app.servos_on)
                app.servo_motor1.writePosition (voltage1);
                pause(0.1);
                app.servo_motor2.writePosition (voltage2);
%                 app.servo_motor3.writePosition (voltages(3));
            end

            sv1 = string(voltage1);
            sv2 = string(voltage2);
%             sv3 = string(voltages(3));
            app.DebugLog.Value = ["V1 = " + sv1;"V2 = " + sv2];
                  
        end

        function update_fkine(app)
            % The core function that plots the current jointangles
            % and draws a sphere on the plot if the end effector is 
            % below the contacting threshold. It also calls
            % the update_motors function if enabled
            app.robot.plot(app.jointangle);
            T = app.robot.fkine(app.jointangle);
            if (T.t(3) <= 0.2)
                plot_sphere(T.t, 0.2, '#FF55AA');
            end
            app.XLabel.Text = string(T.t(1));
            app.YLabel.Text = string(T.t(2));
            app.ZLabel.Text = string(T.t(3));

            % If we checked the affect servos box, update the motors
            if (app.AffectServosCheckBox.Value)
                app.update_motors();
%                   app.DebugLog.Value = "updating motors";
            end

        end
        
        function moveToPos(app, x, y, steps)
            % Moves EE to a given x, y in certain amount of steps
            try
                % Get current T matrix
                T = app.robot.fkine(app.jointangle);
                currX = T.t(1);
                currY = T.t(2);
                points = zeros(steps + 1, 2);
                
                % Generate a list of intermediate points along the way
                deltaX = x - currX;
                deltaY = y - currY;
                stepX = deltaX / steps;
                stepY = deltaY / steps;
                points(:, 1) = currX: stepX: x;
                points(:, 2) = currY: stepY: y;

                % Move along these list of points
                app.xyDeltaMove(points);
            catch
                app.DebugLog.Value = "Unreachable location";
                return;
            end
        end

        function xyDeltaMove(app, points)
            % Moves robot across a series of points
            pointArraySize = size(points);
            steps = pointArraySize(1);
            for i = 1:steps
                try
                    % Get current transformation but set x/y values
                    % to target x and y
                    T = app.robot.fkine(app.jointangle);
                    T.t(1)= points(i, 1);
                    T.t(2) = points(i, 2);
                    % Use ikine to generate new joint angles
                    app.jointangle = app.robot.ikine(T, app.jointangle, 'mask', [1 1 1 0 0 0]);
                    
                    % Update slider values
                    app.slider1.Value = app.jointangle(1);
                    app.slider2.Value = app.jointangle(2);
                    app.slider3.Value = app.jointangle(3);
                    
                    % Move the robot
                    app.update_fkine();
                    app.DebugLog.Value = "Ikine Success";
                catch
                    app.DebugLog.Value = "Ikine Failure";
                end
                app.DebugLog.Value = "Clear";
            end
        end
        
        function update_board(app)
            app.Board1.Text = string(app.board(1,1));
            app.Board2.Text = string(app.board(1,2));
            app.Board3.Text = string(app.board(1,3));
            app.Board4.Text = string(app.board(2,1));
            app.Board5.Text = string(app.board(2,2));
            app.Board6.Text = string(app.board(2,3));
            app.Board7.Text = string(app.board(3,1));
            app.Board8.Text = string(app.board(3,2));
            app.Board9.Text = string(app.board(3,3));
        end


        function DrawX(app, square)
            app.DebugLog.Value = "Draw X at " + string(square);
%             x = app.boardX.Value;
%             y = app.boardY.Value;
%             s = app.boardS.Value / 3; % per square scale***
%             
%             offx = mod(square - 1, 3);
%             offy = floor((square - 1)/3);
%             x = x + offx * s;
%             y = y - offy * s;
%             stepct = 30;
% 
%             app.PenUp();
%             app.moveToPos(x + s/4, y - s/4, 1);
%             app.PenDown();
%             app.moveToPos(x + 3*s/4, y - 3*s/4, stepct);
%             app.PenUp();
%             app.moveToPos(x + 3*s/4, y - s/4, 1);
%             app.PenDown();
%             app.moveToPos(x + s/4, y - 3*s/4, stepct);
%             app.PenUp();            
        end
        
        function DrawO(app, square)
            app.DebugLog.Value = "Draw O at " + string(square);
%             x = app.boardX.Value;
%             y = app.boardY.Value;
%             s = app.boardS.Value / 3; % per square scale***
%             stepCirle = 30;
% 
%             offx = mod(square - 1, 3);
%             offy = floor((square - 1)/3);
%             x = x + offx * s;
%             y = y - offy * s;
%             moveSteps = 1;
%             % cpts are the list of intermediary points along the circle
%             cpts = zeros(2, stepCirle);
%             for i = 0: stepCirle
%                 cpts(1, i+1) = x + s/2 + s/4*cos(2*pi/stepCirle*i);
%                 cpts(2, i+1) = y - s/2 + s/4*sin(2*pi/stepCirle*i);
%             end
%             app.PenUp();
%             app.moveToPos(cpts(1, 1), cpts(2, 1), moveSteps);
%             app.PenDown();
%             
%             for i = 1: stepCirle
%                 app.moveToPos(cpts(1, i), cpts(2, i), moveSteps);
%             end
%             app.moveToPos(cpts(1,1), cpts(2,1), moveSteps);
%             app.PenUp();

        end
        

        function PenUp(app)
            T = app.robot.fkine(app.jointangle);
            z = T.t(3);
            % Just lift the pen
            while z < 0.5
                app.jointangle(3) = app.jointangle(3) - 0.1;
                T = app.robot.fkine(app.jointangle);
                z = T.t(3);
            end
            app.update_fkine();
            if app.AffectServosCheckBox.Value
                app.servo_motor3.writePosition(0.5);
            end
            app.pen_up = true;
        end
        function PenDown(app)
            T = app.robot.fkine(app.jointangle);
            T.t(3) = 0;
            app.jointangle = app.robot.ikine(T, app.jointangle, 'mask', [1 1 1 0 0 0]);
            app.update_fkine();
            if app.AffectServosCheckBox.Value
                app.servo_motor3.writePosition(0.2);
            end
            app.pen_up = false;
        end

        function [] = clearBoard(app)
            app.game.board = zeros(3,3);
            for i=1:3
                for j=1:3
                    eval("app.Board" + string((i-1)*3 + j) + ".Text = ""-"";");
                end
            end
            
        end


        function results = isGameOver(app)
            results = 0;
            [res, winner, ~] = app.game.checkForWin();
            if res
                app.UserDropDown.Enable = "on";
                if winner == app.game.X
                    app.DebugLog.Value = "GAME OVER! X IS THE WINNER";
                else
                    app.DebugLog.Value = "GAME OVER! O IS THE WINNER";
                end

                results = 1;
            end  
            if ~app.game.isMoveLeft()
                app.UserDropDown.Enable = "on";
                app.DebugLog.Value = "GAME TIED!";
                results = 1;
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: Connect
        function Connect_Callback(app, event)
            com = char (inputdlg ('What COM Port?' , 'COM Select', 1, {'/dev/cu.usbmodem1101', 'COM5'}));
            try
                app.device = arduino (com, 'uno', 'libraries', 'Servo');
            catch
                msgbox ('Port failed', com, 'Error');
                return;
            end
            app.data_available = true;
            msgbox ('Port successfully opened');
        end

        % Button pushed function: Create
        function Create_Callback(app, event)
            % Define DH parameters and offsets and qlims
            L(1) = Link([0 0 6.8773 0.0005 0]);
            L(1).offset = -0.2174; % -0.2176
            L(1).qlim = [-pi 0];
            L(2) = Link([0 2.7814 9.5840 3.0 0]);
            L(2).offset = -0.1581; % -0.1580
            L(2).qlim = [-pi 0];
            L(3) =  Link('prismatic', 'theta', -0.0008, 'a', -0.0009, 'alpha', -0.0007);
            L(3).qlim = [0 12];

            % Create robot
            app.robot = SerialLink(L, 'name', 'robot');
            
            % Adjust base
            t = eul2tr([pi/2, 0, 0]);
            t(1:3, 4) = [7.9 0 7.6];
            app.robot.base = t;
            
            % Plot robot
            app.update_fkine();
            
            app.game = TicTacToe(app.robot);

            app.enable_buttons();
            set(app.Board2, 'Enable', 'on');
            set(app.Board3, 'Enable', 'on');
            
        end

        % Menu selected function: ConnectMotorMenu
        function ConnectMotor(app, event)
            if (app.data_available)
                app.servo_motor1 = app.device.servo ('D3','MinPulseDuration',1.5e-3,'MaxPulseDuration',2e-3);
                app.servo_motor2 = app.device.servo ('D5','MinPulseDuration',1.5e-3,'MaxPulseDuration',2e-3);
                app.servo_motor3 = app.device.servo ('D6','MinPulseDuration',1.5e-3,'MaxPulseDuration',1.75e-3);
                app.servos_on = true;
                app.DebugLog.Value = "MOTORS CONNECTED";
            else
                app.DebugLog.Value = "Motor Connect Failed";
            end
           
        end

        % Menu selected function: Disconnect
        function Disconnect_Callback(app, event)
            try
                app.device.delete;
            catch
                msgbox('someing went wrong with port close.')
                return;
            end
            msgbox ('Device deleted');
            
            
        end

        % Callback function: figure1, figure1
        function MoveWithKey(app, event)
            key = event.Key;
            disp (key);
            
            if (strcmp (key, 'leftarrow'))
                app.base_joint = app.base_joint -.1;
            elseif (strcmp (key, 'rightarrow'))
                app.base_joint = app.base_joint +.1;
            end
            
            app.robot.plot ([app.base_joint, 0]);
        end

        % Button pushed function: MoveToButton
        function MoveToButtonPushed(app, event)
            goalX = app.XSpinner.Value;
            goalY = app.YSpinner.Value;
            steps = app.StepsSpinner.Value;
            try
                app.moveToPos(goalX, goalY, steps);
            catch
                app.DebugLog.Value = "FAILURE";
            end
        end

        % Button pushed function: Board1
        function Board1ButtonPushed(app, event)
            if app.game.user == app.game.X
                app.game.board(1,1) = app.game.X;
                app.Board1.Text = 'X';
                app.DrawX(1);
            else
                app.game.board(1,1) = app.game.O;
                app.Board1.Text = 'O';
                app.DrawO(1);
            end
            uiresume(app.figure1);
%             app.DrawO(1);
        end

        % Value changed function: slider1
        function slider1ValueChanged(app, event)
            value = app.slider1.Value;
            app.jointangle(1) = value;
            update_fkine(app);
        end

        % Value changed function: slider2
        function slider2ValueChanged(app, event)
            value = app.slider2.Value;
            app.jointangle(2) = value;
            update_fkine(app);
        end

        % Value changed function: slider3
        function slider3ValueChanged(app, event)
            value = app.slider3.Value;
            app.jointangle(3) = value;
            update_fkine(app);
        end

        % Button pushed function: PenUpDownButton
        function PenUpDownButtonPushed(app, event)
            % Toggle pen button
            if app.pen_up == true
                app.PenDown();
                app.PenUpDownButton.Text = "Pen Up";
            else
                app.PenUp();
                app.PenUpDownButton.Text = "Pen Down";
            end
        end

        % Button pushed function: DrawBoardButton
        function DrawBoardButtonPushed(app, event)
            x = app.boardX.Value;
            y = app.boardY.Value;
            s = app.boardS.Value;
            stepCt = 30;
            % Left vert
            app.PenUp();
            app.moveToPos(x + s/3, y, stepCt);
            app.PenDown();
            app.moveToPos(x + s/3, y-s, stepCt);

            % Right vert
            app.PenUp();
            app.moveToPos(x + 2*s/3, y-s, stepCt);
            app.PenDown();
            app.moveToPos(x + 2*s/3, y, stepCt);
            
            % Top horiz
            app.PenUp();
            app.moveToPos(x+s, y - s/3, stepCt);
            app.PenDown();
            app.moveToPos(x, y - s/3, stepCt);

            % Bottom horiz
            app.PenUp();
            app.moveToPos(x, y - 2*s/3, stepCt);
            app.PenDown();
            app.moveToPos(x+s, y - 2*s/3, stepCt);
        end

        % Button pushed function: Board2
        function Board2ButtonPushed(app, event)
            if app.game.user == app.game.X
                app.game.board(1,2) = app.game.X;
                app.Board2.Text = 'X';
                app.DrawX(2);
            else
                app.game.board(1,2) = app.game.O;
                app.Board2.Text = 'O';
                app.DrawO(2);
            end
            uiresume(app.figure1);
% %             app.DrawO(2);
        end

        % Button pushed function: Board3
        function Board3ButtonPushed(app, event)
            if app.game.user == app.game.X
                app.game.board(1,3) = app.game.X;
                app.Board3.Text = 'X';
                app.DrawX(3);
            else
                app.game.board(1,3) = app.game.O;
                app.Board3.Text = 'O';
                app.DrawO(3);
            end
            uiresume(app.figure1);
% %             app.DrawO(3);
        end

        % Button pushed function: Board4
        function Board4ButtonPushed(app, event)
            if app.game.user == app.game.X
                app.game.board(2,1) = app.game.X;
                app.Board4.Text = 'X';
                app.DrawX(4);
            else
                app.game.board(2,1) = app.game.O;
                app.Board4.Text = 'O';
                app.DrawO(4);
            end
            uiresume(app.figure1);
%             app.DrawO(4);
        end

        % Button pushed function: Board5
        function Board5ButtonPushed(app, event)
            if app.game.user == app.game.X
                app.game.board(2,2) = app.game.X;
                app.Board5.Text = 'X';
                app.DrawX(5);
            else
                app.game.board(2,2) = app.game.O;
                app.Board5.Text = 'O';
                app.DrawO(5);
            end
            uiresume(app.figure1);
%             app.DrawO(5);
        end

        % Button pushed function: Board6
        function Board6ButtonPushed(app, event)
            if app.game.user == app.game.X
                app.game.board(2,3) = app.game.X;
                app.Board6.Text = 'X';
                app.DrawX(6);
            else
                app.game.board(2,3) = app.game.O;
                app.Board6.Text = 'O';
                app.DrawO(6);
            end
            uiresume(app.figure1);
%             app.DrawO(6);
        end

        % Button pushed function: Board7
        function Board7ButtonPushed(app, event)
            if app.game.user == app.game.X
                app.game.board(3,1) = app.game.X;
                app.Board7.Text = 'X';
                app.DrawX(7);
            else
                app.game.board(3,1) = app.game.O;
                app.Board7.Text = 'O';
                app.DrawO(7);
            end
            uiresume(app.figure1);
%             app.DrawO(7);
        end

        % Button pushed function: Board8
        function Board8ButtonPushed(app, event)
            if app.game.user == app.game.X
                app.game.board(3,2) = app.game.X;
                app.Board8.Text = 'X';
                app.DrawX(8);
            else
                app.game.board(3,2) = app.game.O;
                app.Board8.Text = 'O';
                app.DrawO(8);
            end
            uiresume(app.figure1);
%             app.DrawO(8);
        end

        % Button pushed function: Board9
        function Board9ButtonPushed(app, event)
            if app.game.user == app.game.X
                app.game.board(3,3) = app.game.X;
                app.Board9.Text = 'X';
                app.DrawX(9);
            else
                app.game.board(3,3) = app.game.O;
                app.Board9.Text = 'O';
                app.DrawO(9);
            end
            uiresume(app.figure1);
%             app.DrawO(9);
        end

        % Button pushed function: StartGameButton
        function StartGameButtonPushed(app, event)
            app.clearBoard();
            set(app.Board1, 'Enable', 'on');
            set(app.Board2, 'Enable', 'on');
            set(app.Board3, 'Enable', 'on');
            set(app.Board4, 'Enable', 'on');
            set(app.Board5, 'Enable', 'on');
            set(app.Board6, 'Enable', 'on');
            set(app.Board7, 'Enable', 'on');
            set(app.Board8, 'Enable', 'on');
            set(app.Board9, 'Enable', 'on');
            app.enable_buttons();
            app.UserDropDown.Enable = "off";
            app.DebugLog.Value = "";
            if app.game.user == app.game.X
                uiwait(app.figure1);
            end
            while true
                
                if app.isGameOver()
                    app.disable_buttons();
                    return;
                end

                square = app.game.ComputeNextMove();
                if app.game.playingAs == app.game.O
                    app.DrawO((square(1) - 1)*3 + square(2));
                    eval("app.Board" + string((square(1) - 1)*3 + square(2)) + ".Text = ""O"";");
                    app.game.board(square(1), square(2)) = 100;
                else
                    app.DrawX((square(1) - 1)*3 + square(2));
                    eval("app.Board" + string((square(1) - 1)*3 + square(2)) + ".Text = ""X"";");
                    app.game.board(square(1), square(2)) = 10;
                end
                
                if app.isGameOver()
                    app.disable_buttons();
                    return
                end

                uiwait(app.figure1);

            end
        end

        % Value changed function: UserDropDown
        function UserDropDownValueChanged(app, event)
            value = app.UserDropDown.Value;
            if strcmp(value, 'X')
                app.game.playingAs = app.game.O;
                app.game.user = app.game.X;
            else
                app.game.playingAs = app.game.X;
                app.game.user = app.game.O;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'twolink';
            app.figure1.KeyPressFcn = createCallbackFcn(app, @MoveWithKey, true);
            app.figure1.KeyReleaseFcn = createCallbackFcn(app, @MoveWithKey, true);
            app.figure1.Tag = 'figure1';
            app.Arduino = uimenu(app.figure1);
            app.Arduino.Text = 'Arduino';
            app.Arduino.Tag = 'Arduino';
            app.Connect = uimenu(app.Arduino);
            app.Connect.MenuSelectedFcn = createCallbackFcn(app, @Connect_Callback, true);
            app.Connect.Text = 'Connect';
            app.Connect.Tag = 'Connect';
            app.Disconnect = uimenu(app.Arduino);
            app.Disconnect.MenuSelectedFcn = createCallbackFcn(app, @Disconnect_Callback, true);
            app.Disconnect.Text = 'Disconnect';
            app.Disconnect.Tag = 'Disconnect';
            app.ConnectMotorMenu = uimenu(app.Arduino);
            app.ConnectMotorMenu.MenuSelectedFcn = createCallbackFcn(app, @ConnectMotor, true);
            app.ConnectMotorMenu.Text = 'ConnectMotor';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.Create = uibutton(app.figure1, 'push');
            app.Create.ButtonPushedFcn = createCallbackFcn(app, @Create_Callback, true);
            app.Create.Tag = 'Create';
            app.Create.Text = 'Create Robot';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Tic Tac Toe';
            app.XLabel = uilabel(app.figure1);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.figure1);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.figure1);
            app.ZLabel.Text = 'Z';
            app.DebugLogLabel = uilabel(app.figure1);
            app.DebugLogLabel.Text = 'Debug Log';
            app.DebugLog = uitextarea(app.figure1);
            app.Board1 = uibutton(app.figure1, 'push');
            app.Board1.ButtonPushedFcn = createCallbackFcn(app, @Board1ButtonPushed, true);
            app.Board1.Enable = 'off';
            app.Board1.Text = '-';
            app.Board2 = uibutton(app.figure1, 'push');
            app.Board2.ButtonPushedFcn = createCallbackFcn(app, @Board2ButtonPushed, true);
            app.Board2.Enable = 'off';
            app.Board2.Text = '-';
            app.Board3 = uibutton(app.figure1, 'push');
            app.Board3.ButtonPushedFcn = createCallbackFcn(app, @Board3ButtonPushed, true);
            app.Board3.Enable = 'off';
            app.Board3.Text = '-';
            app.Board4 = uibutton(app.figure1, 'push');
            app.Board4.ButtonPushedFcn = createCallbackFcn(app, @Board4ButtonPushed, true);
            app.Board4.Enable = 'off';
            app.Board4.Text = '-';
            app.Board5 = uibutton(app.figure1, 'push');
            app.Board5.ButtonPushedFcn = createCallbackFcn(app, @Board5ButtonPushed, true);
            app.Board5.Enable = 'off';
            app.Board5.Text = '-';
            app.Board6 = uibutton(app.figure1, 'push');
            app.Board6.ButtonPushedFcn = createCallbackFcn(app, @Board6ButtonPushed, true);
            app.Board6.Enable = 'off';
            app.Board6.Text = '-';
            app.Board7 = uibutton(app.figure1, 'push');
            app.Board7.ButtonPushedFcn = createCallbackFcn(app, @Board7ButtonPushed, true);
            app.Board7.Enable = 'off';
            app.Board7.Text = '-';
            app.Board8 = uibutton(app.figure1, 'push');
            app.Board8.ButtonPushedFcn = createCallbackFcn(app, @Board8ButtonPushed, true);
            app.Board8.Enable = 'off';
            app.Board8.Text = '-';
            app.Board9 = uibutton(app.figure1, 'push');
            app.Board9.ButtonPushedFcn = createCallbackFcn(app, @Board9ButtonPushed, true);
            app.Board9.Enable = 'off';
            app.Board9.Text = '-';
            app.XSpinnerLabel = uilabel(app.figure1);
            app.XSpinnerLabel.Text = 'X';
            app.XSpinner = uispinner(app.figure1);
            app.YSpinnerLabel = uilabel(app.figure1);
            app.YSpinnerLabel.Text = 'Y';
            app.YSpinner = uispinner(app.figure1);
            app.StepsSpinnerLabel = uilabel(app.figure1);
            app.StepsSpinnerLabel.Text = 'Steps';
            app.StepsSpinner = uispinner(app.figure1);
            app.StepsSpinner.Step = 10;
            app.MoveToButton = uibutton(app.figure1, 'push');
            app.MoveToButton.ButtonPushedFcn = createCallbackFcn(app, @MoveToButtonPushed, true);
            app.MoveToButton.Tag = 'Create';
            app.MoveToButton.Enable = 'off';
            app.MoveToButton.Text = 'Move to X,Y';
            app.AffectServosCheckBox = uicheckbox(app.figure1);
            app.AffectServosCheckBox.Text = 'Affect Servos';
            app.Joint1SliderLabel = uilabel(app.figure1);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.slider1 = uislider(app.figure1);
            app.slider1.Limits = [-1.2 1.14];
            app.slider1.ValueChangedFcn = createCallbackFcn(app, @slider1ValueChanged, true);
            app.slider1.Enable = 'off';
            app.Joint2Label = uilabel(app.figure1);
            app.Joint2Label.Text = 'Joint 2';
            app.slider2 = uislider(app.figure1);
            app.slider2.Limits = [-2.18 -0.43];
            app.slider2.MajorTicks = [-2.18 -1.6 -1 -0.43];
            app.slider2.ValueChangedFcn = createCallbackFcn(app, @slider2ValueChanged, true);
            app.slider2.Enable = 'off';
            app.slider2.Value = -0.43;
            app.ArmLabel = uilabel(app.figure1);
            app.ArmLabel.Text = 'Pen Joint';
            app.slider3 = uispinner(app.figure1);
            app.slider3.Limits = [-1 15];
            app.slider3.ValueChangedFcn = createCallbackFcn(app, @slider3ValueChanged, true);
            app.slider3.Enable = 'off';
            app.slider3.Value = 1;
            app.PenUpDownButton = uibutton(app.figure1, 'push');
            app.PenUpDownButton.ButtonPushedFcn = createCallbackFcn(app, @PenUpDownButtonPushed, true);
            app.PenUpDownButton.Enable = 'off';
            app.PenUpDownButton.Text = 'Pen Down';
            app.boardX = uieditfield(app.figure1, 'numeric');
            app.boardX.Value = 14;
            app.boardY = uieditfield(app.figure1, 'numeric');
            app.boardY.Value = 8;
            app.boardS = uieditfield(app.figure1, 'numeric');
            app.boardS.Value = 6;
            app.DrawBoardButton = uibutton(app.figure1, 'push');
            app.DrawBoardButton.ButtonPushedFcn = createCallbackFcn(app, @DrawBoardButtonPushed, true);
            app.DrawBoardButton.Enable = 'off';
            app.DrawBoardButton.Text = 'Draw Board';
            app.StartGameButton = uibutton(app.figure1, 'push');
            app.StartGameButton.ButtonPushedFcn = createCallbackFcn(app, @StartGameButtonPushed, true);
            app.StartGameButton.Text = 'Start Game';
            app.UserDropDownLabel = uilabel(app.figure1);
            app.UserDropDownLabel.Text = 'User';
            app.UserDropDown = uidropdown(app.figure1);
            app.UserDropDown.Items = {'X', 'O'};
            app.UserDropDown.ValueChangedFcn = createCallbackFcn(app, @UserDropDownValueChanged, true);
            app.UserDropDown.Value = 'X';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ThreeLinkServoRRP

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end